classdef videoslice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SLICEButton                 matlab.ui.control.StateButton
        PictureFormatDropDown       matlab.ui.control.DropDown
        PictureFormatDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        format;
        switch_start=0;
        video;
        selectedPath;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: PictureFormatDropDown
        function PictureFormatDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            formats= {'.jpg','.jpeg','.png','.tiff'};
            app.format=formats(item);
            app.switch_start=1;
        end

        % Callback function: not associated with a component
        function videoslicer(app, event)
            if app.switch_start==0
                app.format={'.jpg'};
            end
            [filename, pathname] = uigetfile({'*.mp4;*.avi;*.mkv;*.mj2;*.mpg;*.wmv;*.asf;*.m4v;*.mov', 'Video Files (*.mp4;*.avi;*.mkv;*.mj2;*.mpg;*.wmv;*.asf;*.m4v;*.mov)'});
            app.video = VideoReader(fullfile(pathname, filename));
            app.selectedPath = uigetdir;
            
        end

        % Value changed function: SLICEButton
        function SLICEButtonValueChanged(app, event)
            value = app.SLICEButton.Value;
            if value==1
                videoslicer(app,event);
            end
            a=1;
            while hasFrame(app.video) && value
                frame = readFrame(app.video);
                pic_name = string(a) + app.format;
                imwrite(gpuArray(frame),fullfile(app.selectedPath, pic_name));
                pause(0.01);
                value = app.SLICEButton.Value;
                a=a+1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PictureFormatDropDownLabel = uilabel(app.UIFigure);
            app.PictureFormatDropDownLabel.Text = 'Picture Format :';
            app.PictureFormatDropDown = uidropdown(app.UIFigure);
            app.PictureFormatDropDown.Items = {'jpg', 'jpeg', 'png', 'tiff'};
            app.PictureFormatDropDown.ItemsData = {'''.jpg''', '''.jpeg''', '''.png''', '''.tiff'''};
            app.PictureFormatDropDown.ClickedFcn = createCallbackFcn(app, @PictureFormatDropDownClicked, true);
            app.PictureFormatDropDown.Value = '''.jpg''';
            app.SLICEButton = uibutton(app.UIFigure, 'state');
            app.SLICEButton.ValueChangedFcn = createCallbackFcn(app, @SLICEButtonValueChanged, true);
            app.SLICEButton.Text = 'SLICE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = videoslice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end